classdef trackPartitionGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure                   % Track Pa...
        TabGroup              matlab.ui.container.TabGroup       % Load Fil...
        Tab                   matlab.ui.container.Tab            % Load Files
        Button                matlab.ui.control.Button           % Load Mov...
        Button2               matlab.ui.control.Button           % Load Mov...
        ListBox3              matlab.ui.control.ListBox          % Load a MD
        ListBox4              matlab.ui.control.ListBox          % Load a MD
        LabelListBox          matlab.ui.control.Label            % Track MD
        ListBox               matlab.ui.control.ListBox          % Load a M...
        LabelListBox2         matlab.ui.control.Label            % Mask MD
        ListBox2              matlab.ui.control.ListBox          % Load a M...
        Button3               matlab.ui.control.Button           % Add pair...
        Button4               matlab.ui.control.Button           % Remove f...
        Button8               matlab.ui.control.Button           % Add all ...
        Button9               matlab.ui.control.Button           % Remove a...
        Tab2                  matlab.ui.container.Tab            % Set Params
        LabelSpinner2         matlab.ui.control.Label            % Min. Mas...
        Spinner2              matlab.ui.control.Spinner          % [0 Inf]
        LabelSlider           matlab.ui.control.Label            % Min. Tra...
        Slider                matlab.ui.control.Slider           % [1 25]
        Spinner               matlab.ui.control.Spinner          % [1 25]
        LabelNumericEditField matlab.ui.control.Label            % Gaussian...
        NumericEditField      matlab.ui.control.NumericEditField % [0 1.000...
        Label                 matlab.ui.control.Label            % Upscale
        Slider2               matlab.ui.control.Slider           % [1 10]
        Spinner3              matlab.ui.control.Spinner          % [1 10]
        LabelSpinner4         matlab.ui.control.Label            % Analysis...
        Spinner4              matlab.ui.control.Spinner          % [1 199]
        Label3                matlab.ui.control.Label            % Analysis...
        Spinner5              matlab.ui.control.Spinner          % [2 500]
        LabelSwitch           matlab.ui.control.Label            % Perform ...
        Switch                matlab.ui.control.Switch           % No, Yes
        Button5               matlab.ui.control.Button           % Apply to...
        CheckBox2             matlab.ui.control.CheckBox         % Use exis...
        Tab3                  matlab.ui.container.Tab            % Run
        Button6               matlab.ui.control.Button           % RUN
        LabelGauge            matlab.ui.control.Label            % Jobs Done
        Gauge                 matlab.ui.control.Gauge            % [0 100]
        LabelKnob             matlab.ui.control.Label            % Turbo
        Knob                  matlab.ui.control.Knob             % [1 8]
        TextArea2             matlab.ui.control.TextArea        
        Tab4                  matlab.ui.container.Tab            % Plot
        Button7               matlab.ui.control.Button           % PLOT
        Label4                matlab.ui.control.Label            % Start Frame
        Spinner6              matlab.ui.control.Spinner          % [1 199]
        Label5                matlab.ui.control.Label            % End Frame
        Spinner7              matlab.ui.control.Spinner          % [2 500]
        Label6                matlab.ui.control.Label            % Show Image
        Switch2               matlab.ui.control.Switch           % No, Yes
        Label7                matlab.ui.control.Label            % Use Whit...
        Switch3               matlab.ui.control.Switch           % No, Yes
        Label8                matlab.ui.control.Label            % Show Con...
        Switch4               matlab.ui.control.Switch           % No, Yes
        LabelDropDown         matlab.ui.control.Label            % Plot Subset
        DropDown              matlab.ui.control.DropDown         % All
        CheckBox              matlab.ui.control.CheckBox         % Show box...
        LabelDropDown2        matlab.ui.control.Label            % Simplify...
        DropDown2             matlab.ui.control.DropDown         % None
        Tab5                  matlab.ui.container.Tab            % Help
        TextArea              matlab.ui.control.TextArea        
        Panel                 matlab.ui.container.Panel         
        LabelListBox5         matlab.ui.control.Label            % Jobs
        ListBox5              matlab.ui.control.ListBox          % Add a job
        Label9                matlab.ui.control.Label            % Show Mask
        Switch5               matlab.ui.control.Switch           % No, Yes
    end


    properties (Access = private)
        trackMLPath
        trackMLFile
        maskMLPath
        maskMLFile 
        trackMD
        maskMD
        trackChannel
        maskChannel
        jobList
        nJobs
        nWorkers
        useWorkers
    end

    properties (Access = public)

    end

    methods (Access = private)

        function runTextUpdate(app,string)
            text = app.TextArea2.Value;
            text = [text;{string}];
            app.TextArea2.Value = text;
        end

        function emptyParams = paramCheck(app)
            n = app.nJobs;
            emptyParams = zeros(n,1);
            for i = 1:n
                emptyParams(i) = isempty(app.jobList(i).params);
            end
        end

    end

    methods (Access = public)
        
        function [items,itemsData] = loadML(app,ML)
            nMD = numel(ML.movieDataFile_);
            items = cell(1,nMD);
            itemsData = cell(1,nMD);
            for i = 1:nMD
                [~,name,~] = fileparts(ML.movieDataFile_{i});
                items{i} = name;
                itemsData{i} = ML.movieDataFile_{i};
            end
        end

        function [items,itemsData] = loadCML(app,CML)
            nML = numel(CML.movieListDirectory_);
            items = {};
            itemsData = {};
            for i = 1:nML
                inputFile = load(CML.movieListDirectory_{i});
                [itemsTemp,itemsDataTemp] = loadML(app,inputFile.ML);
                % Append the ML number to the MD name
                itemsTemp = cellfun(@(x) [num2str(i),'-',x],itemsTemp,'UniformOutput',false);
                items = [items,itemsTemp];
                itemsData = [itemsData,itemsDataTemp];
            end
        end

    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Create struct for job list
            app.jobList = struct('trackMD',[],'trackChannel',[],'maskMD',[],...
                'maskChannel',[],'params',[]);
            app.nJobs = 0;
            
            % Empty some objects, set some menu values        
            app.ListBox.Items = {};
            app.ListBox2.Items = {};
            app.ListBox3.Items = {};
            app.ListBox4.Items = {};
            app.ListBox5.Items = {};
            
            app.DropDown.Items = {'All','Inside','Outside'};
            app.DropDown.ItemsData = {'all','inside','outside'};
            app.DropDown2.Items = {'None','All','+Random&Superdiffusive'};
            app.DropDown2.ItemsData = {0,1,2};
            
            app.TextArea.Value = fileread('trackPartitionGUIHelp');
            app.TextArea.FontName = 'Courier';
            
            % Find available number of workers
            cluster = parcluster();
            app.nWorkers = cluster.NumWorkers;
            app.useWorkers = 1;
            app.Knob.Value = 1;
            app.Knob.Limits = [1,app.nWorkers];
            app.Knob.MajorTicks = 1:2:app.nWorkers;
        end

        % Button button pushed function
        function trackMLButtonPushed(app)
            [app.trackMLFile,app.trackMLPath] = uigetfile('*.mat','Select a MovieList',app.trackMLPath);
            
            % Load up file as ML or CML
            inputFile = load([app.trackMLPath,app.trackMLFile]);
            if isfield(inputFile,'CML')
                % Load as a CML
                CML = inputFile.CML;
                [items,itemsData] = loadCML(app,CML);
            else
                % Load as a ML
                ML = inputFile.ML;
                [items,itemsData] = loadML(app,ML);
            end

            app.ListBox.Items = items;   
            app.ListBox.ItemsData = itemsData;  
            app.ListBox.Value = itemsData{1};
            trackListBoxValueChanged(app)
        end

        % Button2 button pushed function
        function maskMLButtonPushed(app)
            [app.maskMLFile,app.maskMLPath] = uigetfile('*.mat','Select a MovieList',app.trackMLPath);
            
            % Load up file as ML or CML
            inputFile = load([app.maskMLPath,app.maskMLFile]);
            if isfield(inputFile,'CML')
                % Load as a CML
                CML = inputFile.CML;
                [items,itemsData] = loadCML(app,CML);
            else
                % Load as a ML
                ML = inputFile.ML;
                [items,itemsData] = loadML(app,ML);
            end
                    
            app.ListBox2.Items = items;   
            app.ListBox2.ItemsData = itemsData;
            app.ListBox2.Value = itemsData{1};
            maskListBoxValueChanged(app)
        end

        % ListBox value changed function
        function trackListBoxValueChanged(app)
            trackMDSelected = app.ListBox.Value;
            % load the selected MD and list available channels
            app.trackMD = load(trackMDSelected);
            app.trackMD = app.trackMD.MD;
            channels = app.trackMD.channels_;
            nChannels = numel(channels);
            items = cell(1,nChannels);
            for i = 1:nChannels
                items{i} = ['Channel ',num2str(i),': ',channels(i).excitationWavelength_];
            end
            app.ListBox3.Items = items;
            app.ListBox3.ItemsData = num2cell(1:nChannels);
            app.ListBox3.Value = 1;
            app.trackChannel = 1;
        end

        % ListBox2 value changed function
        function maskListBoxValueChanged(app)
            maskMDSelected = app.ListBox2.Value;
            % load the selected MD and list available channels
            app.maskMD = load(maskMDSelected);
            app.maskMD = app.maskMD.MD;
            channels = app.maskMD.channels_;
            nChannels = numel(channels);
            items = cell(1,nChannels);
            for i = 1:nChannels
                items{i} = ['Channel ',num2str(i),': ',channels(i).excitationWavelength_];
            end
            app.ListBox4.Items = items;
            app.ListBox4.ItemsData = num2cell(1:nChannels);
            app.ListBox4.Value = 1;
            app.maskChannel = 1;
        end

        % ListBox3 value changed function
        function TrackChannelListBoxValueChanged(app)
            app.trackChannel = app.ListBox3.Value;
        end

        % ListBox4 value changed function
        function MaskChannelListBoxValueChanged(app)
            app.maskChannel = app.ListBox4.Value;
        end

        % Button3 button pushed function
        function addJobButtonPushed(app)
            % Check that the selected MD's have already been run with the needed Processes
            if isempty(app.trackMD.getProcessIndex('TrackingProcess'))
                uialert(app.UIFigure,'Tracking has not been run on the selected Track MD!','Oops!')
            elseif isempty(app.maskMD.getProcessIndex('SubResolutionProcess'))
                uialert(app.UIFigure,'Detection has not been run on the selected Mask MD!','Oops!')
            else
            
                % Add the selected track/mask MD pair to job list
                n = app.nJobs+1;
                app.jobList(n).trackMD = app.trackMD;
                app.trackChannel = app.ListBox3.Value;
                app.jobList(n).trackChannel = app.trackChannel;
                app.jobList(n).maskMD = app.maskMD;
                app.maskChannel = app.ListBox4.Value;
                app.jobList(n).maskChannel = app.maskChannel;
                
                % Check if partitioning has been run before. If so, get the parameters used.
                partitionIndex = app.trackMD.getProcessIndex('TrackPartitionProcess');
                if ~isempty(partitionIndex)
                    app.jobList(n).params = app.trackMD.processes_{partitionIndex}.funParams_;
                    neverRun = '+';
                else
                    neverRun = '*';
                end
                
                % Add the pair to the displayed job list
                jobText = app.ListBox5.Items;
                indexListBox = find(cellfun(@(x) strcmp(app.ListBox.Value,x),app.ListBox.ItemsData));
                indexListBox2 = find(cellfun(@(x) strcmp(app.ListBox2.Value,x),app.ListBox2.ItemsData));
                trackMDName = app.ListBox.Items{indexListBox};
                maskMDName = app.ListBox2.Items{indexListBox2};
                if n == 1;
                    jobText{n} = [neverRun,'track: ',trackMDName,' channel',num2str(app.trackChannel),...
                        char(10),neverRun,'mask: ',maskMDName,' channel',num2str(app.maskChannel)];
                    % Set analysis frame end default value 
                    app.Spinner5.Limits = [1,app.trackMD.nFrames_];
                else
                    jobText{n} = ['-------------',char(10),neverRun,'track: ',trackMDName,...
                        ' channel',num2str(app.trackChannel),char(10),neverRun,'mask: ',maskMDName,...
                        ' channel',num2str(app.maskChannel)];
                end
                app.ListBox5.Items = jobText;
                app.ListBox5.ItemsData = 1:n; % ListBox will return an index when item is selected
                app.ListBox5.Value = n;
                app.nJobs = n;
            end
        end

        % Button4 button pushed function
        function removeJobButtonPushed(app)
            remove = app.ListBox5.Value;
            
            app.jobList(remove) = [];
            app.ListBox5.Items(remove) = [];
            app.nJobs = (app.nJobs)-1;
            app.ListBox5.ItemsData = 1:app.nJobs;
        end

        % Slider value changed function
        function MinTrackLengthSliderValueChanged(app)
            value = app.Slider.Value;
            selectedJob = app.ListBox5.Value;
            % Discretize the value to an odd integer
            integer = round(value);
            if mod(integer,2) == 0
                if value > integer
                    value = integer+1;
                else
                    value = integer-1;
                end
            else
                value = integer;
            end
            app.Slider.Value = value;
            app.Spinner.Value = value;
            app.jobList(selectedJob).params.minTrackLength = value;
        end

        % Spinner value changed function
        function MinTrackLengthSpinnerValueChanged(app)
            value = app.Spinner.Value;
            selectedJob = app.ListBox5.Value;
             % Discretize the value to an odd integer
            integer = round(value);
            if mod(integer,2) == 0
                if value > integer
                    value = integer+1;
                else
                    value = integer-1;
                end
            else
                value = integer;
            end
            app.Slider.Value = value;
            app.Spinner.Value = value;
            app.jobList(selectedJob).params.minTrackLength = value;
        end

        % Spinner2 value changed function
        function MinMaskDiamSpinnerValueChanged(app)
            value = app.Spinner2.Value;
            selectedJob = app.ListBox5.Value;
            app.jobList(selectedJob).params.minMaskDiam = value;
        end

        % NumericEditField value changed function
        function GaussianThreshValueChanged(app)
            value = app.NumericEditField.Value;
            selectedJob = app.ListBox5.Value;
            app.jobList(selectedJob).params.gaussianThresh = value;
        end

        % Slider2 value changed function
        function UpscaleSliderValueChanged(app)
            value = app.Slider2.Value;
            selectedJob = app.ListBox5.Value;
            % Discretize the value to an integer
            app.Slider2.Value = round(value);
            app.Spinner3.Value = round(value);
            app.jobList(selectedJob).params.upscale = round(value);
        end

        % Spinner3 value changed function
        function UpscaleSpinnerValueChanged(app)
            value = app.Spinner3.Value;
            selectedJob = app.ListBox5.Value;
            % Discretize the value to an integer
            app.Slider2.Value = round(value);
            app.Spinner3.Value = round(value);
            app.jobList(selectedJob).params.upscale = value;
        end

        % Spinner4 value changed function
        function StartFrameSpinnerValueChanged(app)
            value = app.Spinner4.Value;
            selectedJob = app.ListBox5.Value;
            app.jobList(selectedJob).params.analysisStart = round(value);
        end

        % Spinner5 value changed function
        function EndFrameSpinnerValueChanged(app)
            value = app.Spinner5.Value;
            selectedJob = app.ListBox5.Value;
            app.jobList(selectedJob).params.analysisEnd = round(value);
        end

        % Switch value changed function
        function DiffAnalSwitchValueChanged(app)
            value = app.Switch.Value;
            selectedJob = app.ListBox5.Value;
            if strcmp(value,'No')
                app.jobList(selectedJob).params.runDiffAnalysis = false;
            else
                app.jobList(selectedJob).params.runDiffAnalysis = true;
            end
        end

        % Button5 button pushed function
        function ApplyAllButtonPushed(app)
            % Apply current settings to all jobs
            for i = 1:app.nJobs
                app.jobList(i).params.minTrackLength = app.Slider.Value;
                app.jobList(i).params.useExistingMask = app.CheckBox2.Value;
                app.jobList(i).params.minMaskDiam = app.Spinner2.Value;
                app.jobList(i).params.gaussianThresh = app.NumericEditField.Value;
                app.jobList(i).params.upscale = round(app.Spinner3.Value);
                app.jobList(i).params.analysisStart = round(app.Spinner4.Value);
                app.jobList(i).params.analysisEnd = round(app.Spinner5.Value);    
                if strcmp(app.Switch.Value,'No')
                    app.jobList(i).params.runDiffAnalysis = false;
                else
                    app.jobList(i).params.runDiffAnalysis = true;
                end
            end
        end

        % Button6 button pushed function
        function RunButtonPushed(app)
            app.TextArea2.Value = {'Checking parameters'};
            
            % Check for empty (unset) params
            emptyParams = paramCheck(app);
            if sum(emptyParams) > 0
                runTextUpdate(app,sprintf('Parameters have not been set for Job %g',find(emptyParams,1,'first')))
%                 error('Parameters have not been set for Job %g',find(emptyParams,1,'first'))
            else
            
                % Set the gauge
                if app.nJobs > 20
                    app.Gauge.MajorTicks = [1:5*round(app.nJobs/20):app.nJobs];
                else
                    app.Gauge.MajorTicks = [1:app.nJobs];
                end
                app.Gauge.Limits = [0,app.nJobs];
                app.Gauge.Value = 0;
                
                runTextUpdate(app,'Starting up...')
                if (numel(gcp('nocreate')) == 0) && (app.useWorkers > 1)
                    % no existing parpool
                    parpool(app.useWorkers)
                elseif app.useWorkers > 1
                    % existing parpool
                    pool = gcp;
                    % create new parpool if number of workers has changed
                    if pool.NumWorkers ~= app.useWorkers
                        delete(gcp('nocreate'))
                        parpool(app.useWorkers)
                    end
                else
                    delete(gcp('nocreate'))
                end
                    
                runTextUpdate(app,'Running...')
                tStartAll = tic;
                % Run each job
                for i = 1:app.nJobs
                    job = app.jobList(i);
                    % Catch errors, such as running out of memory (happens too often)
                    try 
                        tStartJob = tic;
                        process = TrackPartitionProcess(job.trackMD,job.maskMD,...
                            job.trackChannel,job.maskChannel,job.params);
                        process.run;
                        tElapsedJob = toc(tStartJob);
                        runTextUpdate(app,sprintf('Job %g completed in %0.2f s',i,tElapsedJob))
                        
                        % Delete previous process if there is one
                        partitionIndex = job.trackMD.getProcessIndex('TrackPartitionProcess');
                        if ~isempty(partitionIndex)
                            job.trackMD.deleteProcess(partitionIndex);
                        end
                    
                        job.trackMD.addProcess(process);
                        job.trackMD.save;
                        
                        % Mark the job with a ~ in the job list display
                        app.ListBox5.Items{i} = strrep(app.ListBox5.Items{i},'*','~');
                        app.ListBox5.Items{i} = strrep(app.ListBox5.Items{i},'+','~');
                        app.ListBox5.Items{i} = strrep(app.ListBox5.Items{i},'x','~');
                        
                        % Update the job list with the new MD
                        app.jobList(i) = job;
                        app.Gauge.Value = i;
                    catch ME
                        runTextUpdate(app,[sprintf('Job %g encountered an error: ',i),...
                            ME.identifier])
                        
                        % Mark the job with a x in the job list display
                        app.ListBox5.Items{i} = strrep(app.ListBox5.Items{i},'*','x');
                        app.ListBox5.Items{i} = strrep(app.ListBox5.Items{i},'+','x');
                        app.ListBox5.Items{i} = strrep(app.ListBox5.Items{i},'~','x');
                        
                    end
                end
                tElapsedAll = toc(tStartAll);
                tH = floor(tElapsedAll/3600);
                tM = floor((tElapsedAll-tH*3600)/60);
                tS = round(tElapsedAll-tH*3600-tM*60);

                runTextUpdate(app,'**************')
                runTextUpdate(app,sprintf('All jobs completed in %g:%02g:%02g',tH,tM,tS))
            end
        end

        % Button7 button pushed function
        function PlotButtonPushed(app)
            % Collect plot options
            options.timeRange = [app.Spinner6.Value,app.Spinner7.Value];
            if strcmp(app.Switch2.Value,'No')
                options.showImage = false;
            else 
                options.showImage = true;
            end
            
            if strcmp(app.Switch3.Value,'No')
                options.whiteImage = false;
            else 
                options.whiteImage = true;
            end 
            
            if strcmp(app.Switch5.Value,'No')
                options.showMask = false;
            else 
                options.showMask = true;
            end
            
            if strcmp(app.Switch4.Value,'No')
                options.showConf = false;
            else 
                options.showConf = true;
            end

            options.simplifyLin = app.DropDown2.Value;
            options.plotSubset = app.DropDown.Value;
            options.showBoxes = app.CheckBox.Value;
            
            selectedJob = app.ListBox5.Value;
            plotTracksPartGUIWrapper(app.jobList(selectedJob).trackMD,options);
        end

        % DropDown value changed function
        function PlotSubsetValueChanged(app, event)
            value = app.DropDown.Value;
            % Disable the showBoxes check box if plotting only outside tracks
            if strcmp(value,'Outside')
                app.CheckBox.Enable = 'off';
            end
        end

        % ListBox5 value changed function
        function JobListBoxValueChanged(app)
%             if isequal(app.TabGroup.SelectedTab,app.Tab2)
                selectedJob = app.ListBox5.Value;
                if ~isempty(app.jobList(selectedJob).params)
                    app.Slider.Value = app.jobList(selectedJob).params.minTrackLength;
                    app.Spinner.Value = app.jobList(selectedJob).params.minTrackLength;
                    app.Spinner2.Value = app.jobList(selectedJob).params.minMaskDiam;
                    app.NumericEditField.Value = app.jobList(selectedJob).params.gaussianThresh;
                    app.Slider2.Value = app.jobList(selectedJob).params.upscale;
                    app.Spinner3.Value = app.jobList(selectedJob).params.upscale;
                    app.Spinner4.Value = app.jobList(selectedJob).params.analysisStart;
                    app.Spinner5.Value = app.jobList(selectedJob).params.analysisEnd;
                    if app.jobList(selectedJob).params.runDiffAnalysis
                        app.Switch.Value = 'Yes';
                    else
                        app.Switch.Value = 'No';
                    end
                end
%             end
        end

        % Knob value changed function
        function ParpoolKnobValueChanged(app)
            value = app.Knob.Value;
            app.useWorkers = round(value);
            app.Knob.Value = round(value);
        end

        % Button8 button pushed function
        function AddAllButtonPushed(app)
            % Add as many corresponding pairs of MDs as possible
            nTrackMD = numel(app.ListBox.Items);
            nMaskMD = numel(app.ListBox2.Items);
            % Pre-selected channels
            selectedTrackChannel = app.ListBox3.Value;
            selectedMaskChannel = app.ListBox4.Value;
            for i = 1:min(nTrackMD,nMaskMD)
                app.ListBox.Value = app.ListBox.ItemsData{i};
                app.ListBox2.Value = app.ListBox2.ItemsData{i};
                % Apply the pre-selected channels to every job
                app.ListBox3.Value = selectedTrackChannel;
                app.ListBox4.Value = selectedMaskChannel;
                
                trackListBoxValueChanged(app)
                maskListBoxValueChanged(app)
                addJobButtonPushed(app)
            end 
        end

        % Button9 button pushed function
        function RemoveAllButtonPushed(app)
            % Reset job list
            app.ListBox5.Items = {};
            app.ListBox5.ItemsData = {};
            app.ListBox5.Value = {};   
            app.nJobs = 0;
            app.jobList = struct('trackMD',[],'trackChannel',[],'maskMD',[],'maskChannel',[],'params',[]);
        end

        % CheckBox2 value changed function
        function ExistingMaskCheckBoxValueChanged(app)
            value = app.CheckBox2.Value;
            selectedJob = app.ListBox5.Value;
            app.jobList(selectedJob).params.useExistingMask = value;
        end

        % Switch5 value changed function
        function ShowMaskSwitchValueChanged(app)
            value = app.Switch5.Value;
            if strcmp(value,'Yes')
                app.Switch2.Value = 'No';
            end
        end

        % Switch2 value changed function
        function ShowImageSwitchValueChanged(app)
            value = app.Switch2.Value;
            if strcmp(value,'Yes')
                app.Switch5.Value = 'No';
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'Track Partition';
            setAutoResize(app, app.UIFigure, true)
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.Units = 'pixels';
            app.Tab = uitab(app.TabGroup);
            app.Tab.Units = 'pixels';
            app.Tab.Title = 'Load Files';
            app.Button = uibutton(app.Tab, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @trackMLButtonPushed);
            app.Button.Text = 'Load MovieList';
            app.Button2 = uibutton(app.Tab, 'push');
            app.Button2.ButtonPushedFcn = createCallbackFcn(app, @maskMLButtonPushed);
            app.Button2.Text = 'Load MovieList';
            app.ListBox3 = uilistbox(app.Tab);
            app.ListBox3.Items = {'Load a MD'};
            app.ListBox3.ValueChangedFcn = createCallbackFcn(app, @TrackChannelListBoxValueChanged);
            app.ListBox3.Value = 'Load a MD';
            app.ListBox4 = uilistbox(app.Tab);
            app.ListBox4.Items = {'Load a MD'};
            app.ListBox4.ValueChangedFcn = createCallbackFcn(app, @MaskChannelListBoxValueChanged);
            app.ListBox4.Value = 'Load a MD';
            app.LabelListBox = uilabel(app.Tab);
            app.LabelListBox.Text = 'Track MD';
            app.ListBox = uilistbox(app.Tab);
            app.ListBox.Items = {'Load a MovieList'};
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @trackListBoxValueChanged);
            app.ListBox.Value = 'Load a MovieList';
            app.LabelListBox2 = uilabel(app.Tab);
            app.LabelListBox2.Text = 'Mask MD';
            app.ListBox2 = uilistbox(app.Tab);
            app.ListBox2.Items = {'Load a MovieList'};
            app.ListBox2.ValueChangedFcn = createCallbackFcn(app, @maskListBoxValueChanged);
            app.ListBox2.Value = 'Load a MovieList';
            app.Button3 = uibutton(app.Tab, 'push');
            app.Button3.ButtonPushedFcn = createCallbackFcn(app, @addJobButtonPushed);
            app.Button3.Text = 'Add pair to job list->';
            app.Button4 = uibutton(app.Tab, 'push');
            app.Button4.ButtonPushedFcn = createCallbackFcn(app, @removeJobButtonPushed);
            app.Button4.Text = 'Remove from job list <-';
            app.Button8 = uibutton(app.Tab, 'push');
            app.Button8.ButtonPushedFcn = createCallbackFcn(app, @AddAllButtonPushed);
            app.Button8.Text = 'Add all pairs ->>>';
            app.Button9 = uibutton(app.Tab, 'push');
            app.Button9.ButtonPushedFcn = createCallbackFcn(app, @RemoveAllButtonPushed);
            app.Button9.Text = 'Remove all jobs <<<-';
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Units = 'pixels';
            app.Tab2.Title = 'Set Params';
            app.LabelSpinner2 = uilabel(app.Tab2);
            app.LabelSpinner2.Text = 'Min. Mask Diameter (nm)';
            app.Spinner2 = uispinner(app.Tab2);
            app.Spinner2.ValueChangedFcn = createCallbackFcn(app, @MinMaskDiamSpinnerValueChanged);
            app.Spinner2.Limits = [0 Inf];
            app.Spinner2.RoundFractionalValues = 'on';
            app.Spinner2.Value = 100;
            app.LabelSlider = uilabel(app.Tab2);
            app.LabelSlider.Text = 'Min. Track Length';
            app.Slider = uislider(app.Tab2);
            app.Slider.Limits = [1 25];
            app.Slider.MajorTicks = [1 5 9 13 17 21 25];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @MinTrackLengthSliderValueChanged);
            app.Slider.Value = 3;
            app.Spinner = uispinner(app.Tab2);
            app.Spinner.Step = 2;
            app.Spinner.ValueChangedFcn = createCallbackFcn(app, @MinTrackLengthSpinnerValueChanged);
            app.Spinner.Limits = [1 25];
            app.Spinner.ValueDisplayFormat = '%11.2g';
            app.Spinner.Value = 3;
            app.LabelNumericEditField = uilabel(app.Tab2);
            app.LabelNumericEditField.Text = 'Gaussian Threshold';
            app.NumericEditField = uieditfield(app.Tab2, 'numeric');
            app.NumericEditField.ValueChangedFcn = createCallbackFcn(app, @GaussianThreshValueChanged);
            app.NumericEditField.Limits = [0 0.1];
            app.Label = uilabel(app.Tab2);
            app.Label.Text = 'Upscale';
            app.Slider2 = uislider(app.Tab2);
            app.Slider2.Limits = [1 10];
            app.Slider2.MajorTicks = [1 2 3 4 5 6 7 8 9 10];
            app.Slider2.ValueChangedFcn = createCallbackFcn(app, @UpscaleSliderValueChanged);
            app.Slider2.Value = 1;
            app.Spinner3 = uispinner(app.Tab2);
            app.Spinner3.ValueChangedFcn = createCallbackFcn(app, @UpscaleSpinnerValueChanged);
            app.Spinner3.Limits = [1 10];
            app.Spinner3.RoundFractionalValues = 'on';
            app.Spinner3.ValueDisplayFormat = '%11.2g';
            app.Spinner3.Value = 1;
            app.LabelSpinner4 = uilabel(app.Tab2);
            app.LabelSpinner4.Text = 'Analysis Start Frame';
            app.Spinner4 = uispinner(app.Tab2);
            app.Spinner4.ValueChangedFcn = createCallbackFcn(app, @StartFrameSpinnerValueChanged);
            app.Spinner4.Limits = [1 199];
            app.Spinner4.RoundFractionalValues = 'on';
            app.Spinner4.Value = 1;
            app.Label3 = uilabel(app.Tab2);
            app.Label3.Text = 'Analysis End Frame';
            app.Spinner5 = uispinner(app.Tab2);
            app.Spinner5.ValueChangedFcn = createCallbackFcn(app, @EndFrameSpinnerValueChanged);
            app.Spinner5.Limits = [2 500];
            app.Spinner5.RoundFractionalValues = 'on';
            app.Spinner5.Value = 200;
            app.LabelSwitch = uilabel(app.Tab2);
            app.LabelSwitch.Text = 'Perform Diffusion Analysis';
            app.Switch = uiswitch(app.Tab2, 'slider');
            app.Switch.Items = {'No', 'Yes'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @DiffAnalSwitchValueChanged);
            app.Switch.Value = 'Yes';
            app.Button5 = uibutton(app.Tab2, 'push');
            app.Button5.ButtonPushedFcn = createCallbackFcn(app, @ApplyAllButtonPushed);
            app.Button5.Text = 'Apply to all jobs';
            app.CheckBox2 = uicheckbox(app.Tab2);
            app.CheckBox2.ValueChangedFcn = createCallbackFcn(app, @ExistingMaskCheckBoxValueChanged);
            app.CheckBox2.Text = 'Use existing mask if available (overrides upscale setting)';
            app.Tab3 = uitab(app.TabGroup);
            app.Tab3.Units = 'pixels';
            app.Tab3.Title = 'Run';
            app.Button6 = uibutton(app.Tab3, 'push');
            app.Button6.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed);
            app.Button6.Text = 'RUN';
            app.LabelGauge = uilabel(app.Tab3);
            app.LabelGauge.Text = 'Jobs Done';
            app.Gauge = uigauge(app.Tab3, 'circular');
            app.Gauge.MajorTicks = [0 10 20 30 40 50 60 70 80 90 100];
            app.LabelKnob = uilabel(app.Tab3);
            app.LabelKnob.Text = 'Turbo';
            app.Knob = uiknob(app.Tab3, 'continuous');
            app.Knob.Limits = [1 8];
            app.Knob.MajorTicks = [1 3 5 7 8];
            app.Knob.ValueChangedFcn = createCallbackFcn(app, @ParpoolKnobValueChanged);
            app.Knob.Value = 1;
            app.TextArea2 = uitextarea(app.Tab3);
            app.TextArea2.Editable = 'off';
            app.Tab4 = uitab(app.TabGroup);
            app.Tab4.Units = 'pixels';
            app.Tab4.Title = 'Plot';
            app.Button7 = uibutton(app.Tab4, 'push');
            app.Button7.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed);
            app.Button7.Text = 'PLOT';
            app.Label4 = uilabel(app.Tab4);
            app.Label4.Text = 'Start Frame';
            app.Spinner6 = uispinner(app.Tab4);
            app.Spinner6.Limits = [1 199];
            app.Spinner6.RoundFractionalValues = 'on';
            app.Spinner6.Value = 1;
            app.Label5 = uilabel(app.Tab4);
            app.Label5.Text = 'End Frame';
            app.Spinner7 = uispinner(app.Tab4);
            app.Spinner7.Limits = [2 500];
            app.Spinner7.RoundFractionalValues = 'on';
            app.Spinner7.Value = 200;
            app.Label6 = uilabel(app.Tab4);
            app.Label6.Text = 'Show Image';
            app.Switch2 = uiswitch(app.Tab4, 'slider');
            app.Switch2.Items = {'No', 'Yes'};
            app.Switch2.ValueChangedFcn = createCallbackFcn(app, @ShowImageSwitchValueChanged);
            app.Switch2.Value = 'Yes';
            app.Label7 = uilabel(app.Tab4);
            app.Label7.Text = 'Use White Background';
            app.Switch3 = uiswitch(app.Tab4, 'slider');
            app.Switch3.Items = {'No', 'Yes'};
            app.Switch3.Value = 'No';
            app.Label8 = uilabel(app.Tab4);
            app.Label8.Text = 'Show Confinement Radii';
            app.Switch4 = uiswitch(app.Tab4, 'slider');
            app.Switch4.Items = {'No', 'Yes'};
            app.Switch4.Value = 'No';
            app.LabelDropDown = uilabel(app.Tab4);
            app.LabelDropDown.Text = 'Plot Subset';
            app.DropDown = uidropdown(app.Tab4);
            app.DropDown.Items = {'All'};
            app.DropDown.ItemsData = {'''all'',''inside'',''outside'''};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @PlotSubsetValueChanged, true);
            app.DropDown.Value = '''all'',''inside'',''outside''';
            app.CheckBox = uicheckbox(app.Tab4);
            app.CheckBox.Text = 'Show box around inside tracks';
            app.LabelDropDown2 = uilabel(app.Tab4);
            app.LabelDropDown2.Text = 'Simplify Linear Groups';
            app.DropDown2 = uidropdown(app.Tab4);
            app.DropDown2.Items = {'None'};
            app.DropDown2.ItemsData = {'0,1,2'};
            app.DropDown2.Value = '0,1,2';
            app.Label9 = uilabel(app.Tab4);
            app.Label9.Text = 'Show Mask';
            app.Switch5 = uiswitch(app.Tab4, 'slider');
            app.Switch5.Items = {'No', 'Yes'};
            app.Switch5.ValueChangedFcn = createCallbackFcn(app, @ShowMaskSwitchValueChanged);
            app.Switch5.Value = 'No';
            app.Tab5 = uitab(app.TabGroup);
            app.Tab5.Units = 'pixels';
            app.Tab5.Title = 'Help';
            app.TextArea = uitextarea(app.Tab5);
            app.TextArea.Editable = 'off';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Units = 'pixels';
            app.LabelListBox5 = uilabel(app.Panel);
            app.LabelListBox5.Text = 'Jobs';
            app.ListBox5 = uilistbox(app.Panel);
            app.ListBox5.Items = {'Add a job'};
            app.ListBox5.ValueChangedFcn = createCallbackFcn(app, @JobListBoxValueChanged);
            app.ListBox5.Value = 'Add a job';
        end
    end

    methods (Access = public)

        % Construct app
        function app = trackPartitionGUI()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end